classdef icalPR670 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                 matlab.ui.Figure
        FileMenu_2               matlab.ui.container.Menu
        FileMenu                 matlab.ui.container.Menu
        EditMenu                 matlab.ui.container.Menu
        CaptureMenu              matlab.ui.container.Menu
        InitializeMenu           matlab.ui.container.Menu
        MeasureMenu              matlab.ui.container.Menu
        PlotMenu                 matlab.ui.container.Menu
        HelpMenu                 matlab.ui.container.Menu
        FileInformationPanel     matlab.ui.container.Panel
        CommentTextAreaLabel     matlab.ui.control.Label
        CommentTextArea          matlab.ui.control.TextArea
        FilenameEditFieldLabel   matlab.ui.control.Label
        FilenameEditField        matlab.ui.control.EditField
        DirectoryEditFieldLabel  matlab.ui.control.Label
        DirectoryEditField       matlab.ui.control.EditField
        icalPR670Label           matlab.ui.control.Label
        Image                    matlab.ui.control.Image
        WavelengthPanel          matlab.ui.container.Panel
        StartEditFieldLabel      matlab.ui.control.Label
        StartEditField           matlab.ui.control.NumericEditField
        StepEditFieldLabel       matlab.ui.control.Label
        StepEditField            matlab.ui.control.NumericEditField
        StopEditFieldLabel       matlab.ui.control.Label
        StopEditField            matlab.ui.control.NumericEditField
        InstrumentPanel          matlab.ui.container.Panel
        MeasureButton            matlab.ui.control.Button
        PlotButton               matlab.ui.control.Button
        InitPR670Button          matlab.ui.control.Button
        RepeatsSpinnerLabel      matlab.ui.control.Label
        RepeatsSpinner           matlab.ui.control.Spinner
    end

    
    methods (Access = private)
        
        function wave = prGetWave(app)
            % Returns the vector of wavelengths
            start = app.StartEditField.Value;
            step = app.StepEditField.Value;
            stop = app.StopEditField.Value;
            wave = start:step:stop;
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: MeasureButton
        function btnMeasure(app, event)
            disp('Measure button pushed.')
        end

        % Button pushed function: InitPR670Button
        function btnInitPR670(app, event)
            
            % Validate the PsychToolbox command IOPort.
            % We did a clean install of the Psychtoolbox to make sure this
            % runs.  That included the download and the SetupPsychtoolbox
            % command.  The Setup created the correct IOPort mex file.
            str = which('IOPort','-all');
            if isempty(str)
                error('IOPort not on your path.  Check PsychToolbox installation');
            else
                [~,~,e1] = fileparts(str{1});
                [~,~,e2] = fileparts(str{2});
                if isequal(e1,'.mexw64') && isequal(e2,'.m')
                    disp('Found IOPort.mexw64')
                else
                    warndlg('Wrong order of IOPort access.  Should be .mexw64 and then .m')
                end
            end
            
            % Open the COM port to the PR670
            photometerCOM = char(serialportlist('available'));
            fprintf('Available COM port(s):    %s\n,',photometerCOM);
            try
                PR670init(photometerCOM);
            catch
                error('Error initializing PR670');
            end
            disp('PR670 initialized.')
            
            % Create a local folder with the date where the measurements
            % will be stored.  Change into that folder.
            dirName = datestr(now,'yyyy-mm-dd');
            folderName = fullfile(icalRootPath, 'local', dirName);
            if ~exist(folderName, 'dir'), mkdir(folderName); end
            cd(folderName);
            
        end

        % Button pushed function: PlotButton
        function btnPlot(app, event)
            spd = app.Image.UserData;
            wave = prGetWave(app);
            plot(wave,spd);
            xlabel('Wavelength (nm)');
            ylabel('Radiance (watts/sr/m2/nm)')
            set(gca,'yscale','log')
            grid on;
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.FileMenu_2 = uimenu(app.UIFigure);
            app.FileMenu_2.Text = 'File';
            app.FileMenu = uimenu(app.FileMenu_2);
            app.FileMenu.Text = 'File';
            app.EditMenu = uimenu(app.UIFigure);
            app.EditMenu.Text = 'Edit';
            app.CaptureMenu = uimenu(app.UIFigure);
            app.CaptureMenu.Text = 'Capture';
            app.InitializeMenu = uimenu(app.CaptureMenu);
            app.InitializeMenu.Text = 'Initialize';
            app.MeasureMenu = uimenu(app.CaptureMenu);
            app.MeasureMenu.Text = 'Measure';
            app.PlotMenu = uimenu(app.CaptureMenu);
            app.PlotMenu.Text = 'Plot';
            app.HelpMenu = uimenu(app.UIFigure);
            app.HelpMenu.Text = 'Help';
            app.FileInformationPanel = uipanel(app.UIFigure);
            app.FileInformationPanel.Title = 'File Information';
            app.CommentTextAreaLabel = uilabel(app.FileInformationPanel);
            app.CommentTextAreaLabel.Text = 'Comment';
            app.CommentTextArea = uitextarea(app.FileInformationPanel);
            app.FilenameEditFieldLabel = uilabel(app.FileInformationPanel);
            app.FilenameEditFieldLabel.Text = 'File name';
            app.FilenameEditField = uieditfield(app.FileInformationPanel, 'text');
            app.DirectoryEditFieldLabel = uilabel(app.FileInformationPanel);
            app.DirectoryEditFieldLabel.Text = 'Directory';
            app.DirectoryEditField = uieditfield(app.FileInformationPanel, 'text');
            app.icalPR670Label = uilabel(app.UIFigure);
            app.icalPR670Label.Text = 'icalPR670';
            app.Image = uiimage(app.UIFigure);
            app.WavelengthPanel = uipanel(app.UIFigure);
            app.WavelengthPanel.Title = 'Wavelength';
            app.StartEditFieldLabel = uilabel(app.WavelengthPanel);
            app.StartEditFieldLabel.Text = 'Start';
            app.StartEditField = uieditfield(app.WavelengthPanel, 'numeric');
            app.StartEditField.Value = 380;
            app.StepEditFieldLabel = uilabel(app.WavelengthPanel);
            app.StepEditFieldLabel.Text = 'Step';
            app.StepEditField = uieditfield(app.WavelengthPanel, 'numeric');
            app.StepEditField.Value = 5;
            app.StopEditFieldLabel = uilabel(app.WavelengthPanel);
            app.StopEditFieldLabel.Text = 'Stop';
            app.StopEditField = uieditfield(app.WavelengthPanel, 'numeric');
            app.StopEditField.Value = 780;
            app.InstrumentPanel = uipanel(app.UIFigure);
            app.InstrumentPanel.Title = 'Instrument';
            app.MeasureButton = uibutton(app.InstrumentPanel, 'push');
            app.MeasureButton.ButtonPushedFcn = createCallbackFcn(app, @btnMeasure, true);
            app.MeasureButton.Text = 'Measure';
            app.PlotButton = uibutton(app.InstrumentPanel, 'push');
            app.PlotButton.ButtonPushedFcn = createCallbackFcn(app, @btnPlot, true);
            app.PlotButton.Text = 'Plot';
            app.InitPR670Button = uibutton(app.InstrumentPanel, 'push');
            app.InitPR670Button.ButtonPushedFcn = createCallbackFcn(app, @btnInitPR670, true);
            app.InitPR670Button.Text = 'Init PR 670';
            app.RepeatsSpinnerLabel = uilabel(app.InstrumentPanel);
            app.RepeatsSpinnerLabel.Text = 'Repeats';
            app.RepeatsSpinner = uispinner(app.InstrumentPanel);
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = icalPR670

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end